classdef MaquinaMNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MNEDUIFigure                    matlab.ui.Figure
        RelatorioMenu                   matlab.ui.container.Menu
        SobreMenu                       matlab.ui.container.Menu
        AutoresMenu                     matlab.ui.container.Menu
        ISECMenu                        matlab.ui.container.Menu
        SairMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ProblemasDropDown               matlab.ui.control.DropDown
        ProblemasdinmicosDropDownLabel  matlab.ui.control.Label
        GrelhaCheckBox                  matlab.ui.control.CheckBox
        ExecutarButton                  matlab.ui.control.Button
        MetodosNumericosButtonGroup     matlab.ui.container.ButtonGroup
        EulerMButton                    matlab.ui.control.ToggleButton
        TodososMetodosButton            matlab.ui.control.ToggleButton
        RK4Button                       matlab.ui.control.ToggleButton
        RK2Button                       matlab.ui.control.ToggleButton
        EulerButton                     matlab.ui.control.ToggleButton
        DadosdoPVIPanel                 matlab.ui.container.Panel
        eV0                             matlab.ui.control.NumericEditField
        u0Label_2                       matlab.ui.control.Label
        eG                              matlab.ui.control.EditField
        ftuvLabel_2                     matlab.ui.control.Label
        nSpinner                        matlab.ui.control.Spinner
        nSpinnerLabel                   matlab.ui.control.Label
        eU0                             matlab.ui.control.NumericEditField
        u0Label                         matlab.ui.control.Label
        eB                              matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        eA                              matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        ftuvLabel                       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function [t, u, v] = NEuler (~,f, g, a, b, n, u0, v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i = 1:n
                u(i+1) = u(i)+h*f(t(i),u(i),v(i));
                v(i+1) = v(i)+h*g(t(i),u(i),v(i));
            end
        end

        function [t, u, v] = NEulerMelhorado(~,f, g, a, b, n, u0, v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i=1:n
                %calcula-se a funcao f(t,u,v)
                uk1 = f(t(i),u(i),v(i));
                vk1 = g(t(i),u(i),v(i));

                uk2 = f(t(i+1), u(i)+h*uk1, v(i)+h*vk1);
                vk2 = g(t(i+1), u(i)+h*uk1, v(i)+h*vk1);

                u(i+1) = u(i) + h/2*(uk1+uk2);
                v(i+1) = v(i) + h/2*(vk1+vk2);
            end
        end

        function [t, u, v] = RK2(~,f, g, a, b, n, u0, v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i=1:n
                uk1 = h*f(t(i),u(i),v(i));
                vk1 = h*g(t(i),u(i),v(i));

                uk2 = h*f(t(i+1),u(i)+uk1,v(i)+vk1);
                vk2 = h*g(t(i+1),u(i)+uk1,v(i)+vk1);

                u(i+1) = u(i)+(uk1+uk2)/2;
                v(i+1) = v(i)+(vk1+vk2)/2;
            end
        end

        function [t, u, v] = RK4(~,f, g, a, b, n, u0, v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i=1:n
                %calcula-se a funcao f(t,u,v)
                uk1 = f(t(i),u(i),v(i));
                vk1 = g(t(i),u(i),v(i));

                uk2 = f(t(i)+(h/2),u(i)+h*uk1/2,v(i)+h*vk1/2);
                vk2 = g(t(i)+(h/2),u(i)+h*uk1/2,v(i)+h*vk1/2);

                uk3 = f(t(i)+(h/2),u(i)+h*uk2/2,v(i)+h*vk2/2);
                vk3 = g(t(i)+(h/2),u(i)+h*uk2/2,v(i)+h*vk2/2);

                uk4 = f(t(i)+(h/2),u(i)+h*uk3,v(i)+h*vk3);
                vk4 = g(t(i)+(h/2),u(i)+h*uk3,v(i)+h*vk3);

                u(i+1) = u(i)+(h/6)*(uk1+2*uk2+2*uk3+uk4);
                v(i+1) = v(i)+(h/6)*(vk1+2*vk2+2*vk3+vk4);
            end
        end

        function [t, u, v] = MODE45_SED(~, f, g, a, b, n, u0, v0)
            h = (b-a)/n;
            t = a:h:b;
            CI = [ u0; v0];

            odefunc = @(t, y) [f(t, y(1), y(2)); g(t, y(1), y(2))];

            [~, result] = ode45(odefunc, t, CI);
            u = result(:, 1).';
            v = result(:, 2).';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, []);
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            MNumericoSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;

            % helper vector com cores mais faceis aos olhos
            colors = [[0, 0.4470, 0.7410]; ...
                [0.8500, 0.3250, 0.0980]; ...
                [0.9290, 0.6940, 0.1250]; ...
                [0.4940, 0.1840, 0.5560]; ...
                [0.4660, 0.6740, 0.1880]; ...
                [0.3010, 0.7450, 0.9330]; ...
                [0.6350, 0.0780, 0.1840]];

            try
                % Dados
                syms u(t) v(t);
                strF = app.eF.Value;
                f = str2func(vectorize(strcat('@(t,u,v)', " ", strF)));
                strG = app.eG.Value;
                g = str2func(vectorize(strcat('@(t,u,v)', " ", strG)));
                a = app.eA.Value;
                b = app.eB.Value;
                n = app.nSpinner.Value;
                u0 = app.eU0.Value;
                v0 = app.eV0.Value;

                try
                    z = g(t,v,u);
                    app.eF.BackgroundColor = "w";
                catch
                    app.eF.BackgroundColor = [0.9, 0.0680, 0.1840];
                    throw(MException("MATLAB:Executar:badG","Introduza uma função em t, u e v"))
                end

                if (b > a)
                    app.eA.BackgroundColor = "w";
                else
                    app.eA.BackgroundColor = [0.9, 0.0680, 0.1840];
                    throw(MException("MATLAB:Executar:badA", "O valor de a tem que ser inferior a b"))
                end

                % solução exata do problema
                SED = [diff(u,t)==f(t,u,v), diff(v,t)==g(t,u,v)];
                CI = [u(a)==u0, v(a)==v0]; %Condicoes Inciais
                [uExata, ~] = dsolve(SED, CI);

                % resultados e tabela de resultados
                [t, uEuler, vEuler]=NEuler(app,f, g, a, b, n, u0, v0);
                [~, uEulerM, vEulerM]=NEulerMelhorado(app,f, g, a, b, n, u0, v0);
                [~, uRK2, vRK2]=RK2(app,f, g, a, b, n, u0, v0);
                [~, uRK4, vRK4]=RK4(app,f, g, a, b, n, u0, v0);

                if isempty(uExata)

                    [~, uExata, vExata] = MODE45_SED(app,f, g, a, b, n, u0, v0);
                    plot(app.UIAxes,t,uExata,"-o", "Color",colors(1,:));
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,t,vExata,"-o", "Color",colors(2,:));
                    legend(app.UIAxes,"uExata", "vExata");

                    msgbox("Não foi possivel determinar a solução exata da equação");
                    switch MNumericoSel
                        case "Euler"
                            plot(app.UIAxes,t,uEuler,"-_","Color",colors(2,:));
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,t,vEuler,"-*","Color",colors(1,:));
                            A = [t.',uEuler.',vEuler.'];
                        case "Euler M"
                            plot(app.UIAxes,t,uEulerM, "-*","Color",colors(2,:));
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,t,vEulerM,"-_","Color",colors(1,:));
                            A = [t.',uEulerM.',vEulerM.'];
                        case "RK2"
                            plot(app.UIAxes,t,uRK2, "-+","Color",colors(2,:));
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,t,vRK2,"-.","Color",colors(1,:));
                            A = [t.',uRK2.',vRK2.'];
                        case "RK4"
                            plot(app.UIAxes,t,uRK4, "-.","Color",colors(2,:));
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,t,vRK4,"-+","Color",colors(1,:));
                            A = [t.',uRK4.',vRK4.'];
                        case "Todos os Métodos"
                            plot(app.UIAxes,t,uEuler, "-_","Color",colors(2,:));
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,t,uEulerM, "-*","Color",colors(3,:));
                            plot(app.UIAxes,t,uRK2, "-+","Color",colors(4,:));
                            plot(app.UIAxes,t,uRK4, "-.","Color",colors(5,:));

                            plot(app.UIAxes,t,vEuler, "-_","Color",colors(2,:));
                            plot(app.UIAxes,t,vEulerM, "-*","Color",colors(3,:));
                            plot(app.UIAxes,t,vRK2, "-+","Color",colors(4,:));
                            plot(app.UIAxes,t,vRK4, "-.","Color",colors(5,:));

                            legend(app.UIAxes,"uEuler","uEulerM","uRK2","uRK4","vEuler","vEulerM","vRK2","vRK4");
                            A = [t.',uEuler.', uEulerM.',uRK2.', uRK4.',vEuler.', vEulerM.',vRK2.', vRK4.'];
                            nomeColunas = ["t","uEuler","uEulerM","uRK2","uRK4", ...
                                        "vEuler","vEulerM","vRK2","vRK4"];
                        otherwise
                            uialert(app.MNEDUIFigure, "To implement", "Not done");
                    end

                    if ~(MNumericoSel=="Todos os Métodos")
                        legend(app.UIAxes, strcat("u", MNumericoSel), strcat("v", MNumericoSel));
                        nomeColunas = ["t", strcat("u", MNumericoSel), strcat("v", MNumericoSel)];
                    end
                else

                    % solução exata do problema (expressão numérica)
                    h = @(t) eval(vectorize(uExata));

                    uExata = h(t);

                    h = (b-a)/n;
                    t = a:h:b;
                    % calculo dos erros
                    erroEuler = abs(uExata-uEuler);
                    erroEulerM = abs(uExata-uEulerM);
                    erroRK2 = abs(uExata-uRK2);
                    erroRK4 = abs(uExata-uRK4);

                    plot(app.UIAxes,t,uExata,"-o", "Color",colors(1,:));
                    hold(app.UIAxes,"on");
                    legend(app.UIAxes,"Exata");

                    A = [t.', uExata.'];
                    nomeColunas = ["t","Exata"];

                    switch MNumericoSel
                        case "Euler"
                            plot(app.UIAxes,t,uEuler,"-_","Color",colors(2,:));
                            plot(app.UIAxes,t,vEuler,"-*","Color",colors(3,:));
                            A = [t.',uExata.',uEuler.',vEuler.',erroEuler.'];
                        case "Euler M"
                            plot(app.UIAxes,t,uEulerM, "-*","Color",colors(2,:));
                            plot(app.UIAxes,t,vEulerM,"-_","Color",colors(3,:));
                            A = [t.',uExata.',uEulerM.',vEulerM.',erroEulerM.'];
                        case "RK2"
                            plot(app.UIAxes,t,uRK2, "-+","Color",colors(2,:));
                            plot(app.UIAxes,t,vRK2,"-.","Color",colors(3,:));                           
                            A = [t.',uExata.',uRK2.',vRK2.',erroRK2.'];
                        case "RK4"
                            plot(app.UIAxes,t,uRK4, "-.","Color",colors(2,:));
                            plot(app.UIAxes,t,vRK4,"-+","Color",colors(3,:));
                            A = [t.',uExata.',uRK4.',vRK4.',erroRK4.'];
                        case "Todos os Métodos"
                            plot(app.UIAxes,t,uEuler, "-_","Color",colors(2,:));
                            plot(app.UIAxes,t,uEulerM, "-*","Color",colors(3,:));
                            plot(app.UIAxes,t,uRK2, "-+","Color",colors(4,:));
                            plot(app.UIAxes,t,uRK4, "-.","Color",colors(5,:));

                            plot(app.UIAxes,t,vEuler, "-_","Color",colors(2,:));
                            plot(app.UIAxes,t,vEulerM, "-*","Color",colors(3,:));
                            plot(app.UIAxes,t,vRK2, "-+","Color",colors(4,:));
                            plot(app.UIAxes,t,vRK4, "-.","Color",colors(5,:));
                            
                            legend(app.UIAxes,"Exata", ...
                                "uEuler","uEulerM","uRK2","uRK4", ...
                                "vEuler","vEulerM","vRK2","vRK4");
                            A = [t.',uExata.', ...
                                uEuler.', uEulerM.',uRK2.', uRK4.', ...
                                vEuler.', vEulerM.',vRK2.', vRK4.', ...
                                erroEuler.', erroEulerM.', erroRK2.', erroRK4.'];
                            nomeColunas = ["t","Exata", ...
                                "uEuler","uEulerM","uRK2","uRK4", ...
                                 "vEuler","vEulerM","vRK2","vRK4", ...
                                "erroEuler", "erroEulerM", "erroRK2", "erroRK4"];
                        otherwise
                            uialert(app.MNEDUIFigure, "To implement", "Not done");
                    end

                    if ~(MNumericoSel=="Todos os Métodos")
                        legend(app.UIAxes,"Exata",strcat("u", MNumericoSel), strcat("v", MNumericoSel));
                        nomeColunas = ["t", "Exata",strcat("u", MNumericoSel), strcat("v", MNumericoSel),"erro"];
                    end
                end

                % representação gráfica
                hold(app.UIAxes,"off");
                grid(app.UIAxes, app.GrelhaCheckBox.Value);

                app.UITable.Data = array2table(A);
                app.UITable.ColumnName = nomeColunas;

            catch me %matlab exception
                errordlg(me.message, "Erro detetado", "modal");
            end
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            grid(app.UIAxes, app.GrelhaCheckBox.Value);
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("Metodos_Numericos_para_Equacoes_Diferenciais_Ordinarias_e_Problemas_de_Valor_Inicial.docx");
        end

        % Menu selected function: SobreMenu
        function SobreMenuSelected(app, event)

        end

        % Menu selected function: ISECMenu
        function ISECMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autores
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            answer = questdlg("Quer Sair?", "Sair", "Sim", "Não", "Sim");
            if strcmp(answer, "Sim")
                delete(app.MNEDUIFigure)
            end
        end

        % Close request function: MNEDUIFigure
        function MNEDUIFigureCloseRequest(app, event)
            answer = questdlg("Quer Sair?", "Sair", "Sim", "Não", "Sim");
            if strcmp(answer, "Sim")
                delete(app.MNEDUIFigure)
            end
        end

        % Value changed function: ProblemasDropDown
        function ProblemasDropDownValueChanged(app, event)
            value = app.ProblemasDropDown.Value;
            switch value
                case "1" %Mola Massa sem amortecimento
                    strG = "-16*u";
                    u0 = 9; v0 = 0;
                    a = 0; b = 8; n = 100;
                case "2" %Mola Massa com Amortecimento
                    strG = "-25*u-10*v";
                    u0 = 0; v0 = -4;
                    a = 0; b = 15; n = 100;
                case "3" %pendulo
                    strG = "-sin(u)-0.3*v";
                    u0 = pi/2; v0 = 0;
                    a = 1; b = 15; n = 100;
                case "4" %Circuito Elétrico
                    strG = "-u";
                    u0 = 5; v0 = 0;
                    a = 0; b = 20; n = 100;
                case "5" %outro
                    strG = "-0.05*v-0.1*u+2*sin(0.1*t)";
                    u0 = 100; v0 = 5;
                    a = 0; b = 50; n = 100;
                otherwise
                    strG = "u";
                    u0 = 9; v0 = 0;
                    a = 0; b = 8; n = 100;
            end
            app.eG.Value = strG;
            app.eA.Value = a; app.eB.Value = b; app.nSpinner.Value = n;
            app.eU0.Value= u0; app.eV0.Value = v0;
            ExecutarButtonPushed(app, event)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MNEDUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {213, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MNEDUIFigure = uifigure('Visible', 'off');
            app.MNEDUIFigure.Name = 'MNED';
            app.MNEDUIFigure.CloseRequestFcn = createCallbackFcn(app, @MNEDUIFigureCloseRequest, true);
            app.MNEDUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.RelatorioMenu = uimenu(app.MNEDUIFigure);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.SobreMenu = uimenu(app.MNEDUIFigure);
            app.SobreMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreMenuSelected, true);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.ISECMenu = uimenu(app.SobreMenu);
            app.ISECMenu.MenuSelectedFcn = createCallbackFcn(app, @ISECMenuSelected, true);
            app.ISECMenu.Text = 'ISEC';
            app.SairMenu = uimenu(app.MNEDUIFigure);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.GridLayout = uigridlayout(app.MNEDUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.bEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.u0Label = uilabel(app.DadosdoPVIPanel);
            app.u0Label.Text = 'u0 =';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 2;
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.Value = 2;
            app.ftuvLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel_2.Text = 'g(t,u,v) =';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = 'u+v';
            app.u0Label_2 = uilabel(app.DadosdoPVIPanel);
            app.u0Label_2.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eV0.Value = 2;
            app.MetodosNumericosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.TodososMetodosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.GrelhaCheckBox.Value = true;
            app.ProblemasdinmicosDropDownLabel = uilabel(app.LeftPanel);
            app.ProblemasdinmicosDropDownLabel.Text = 'Problemas dinâmicos';
            app.ProblemasDropDown = uidropdown(app.LeftPanel);
            app.ProblemasDropDown.Items = {'Mola Massa sem amortecimento', 'Mola Massa com Amortecimento', 'Pêndulo', 'Circuito Elétrico', 'outro'};
            app.ProblemasDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ProblemasDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemasDropDownValueChanged, true);
            app.ProblemasDropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.MNEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaMNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MNEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MNEDUIFigure)
        end
    end
end